classdef Adicional_NRZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ListBox                       matlab.ui.control.ListBox
        CuldeseasgrficarListBoxLabel  matlab.ui.control.Label
        TipTextArea                   matlab.ui.control.TextArea
        TipTextAreaLabel              matlab.ui.control.Label
        RestablecerButton             matlab.ui.control.Button
        DibujarNRZButton              matlab.ui.control.Button
        nBitsInput                    matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel_2   matlab.ui.control.Label
        GenerarSecuencia              matlab.ui.control.Button
        Duracion                      matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel     matlab.ui.control.Label
        FsInput                       matlab.ui.control.NumericEditField
        FrecuenciademuestreofsEditFieldLabel  matlab.ui.control.Label
        BitSequence                   matlab.ui.control.EditField
        CadenadeBitsEditFieldLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DibujarNRZButton
        function DibujarNRZButtonPushed(app, event)
            try
        % Capturar la secuencia de bits del usuario
        user_input = num2str(app.BitSequence.Value);

        % Limpiar caracteres no binarios
        user_input = regexprep(user_input, '[^01]', ''); 

        % Verificar si la entrada está vacía después de limpiar
        if isempty(user_input)
            error("Ingrese al menos un bit válido.");
        end

        % Convertir la cadena en un vector numérico
        data_bits = double(user_input) - '0';

            catch
                uialert(app.UIFigure, 'Ingrese una secuencia binaria válida (ej: 1010101).', 'Error');
                return;
            end

    % Obtener parámetros de entrada
    fs = app.FsInput.Value;       % Frecuencia de muestreo
    duration = app.Duracion.Value; % Duración de la señal en segundos
    num_bits = length(data_bits);

    % Calcular el bit rate dinámicamente
    bit_rate = num_bits / duration;
    bit_period = 1 / bit_rate;

    % Crear el eje de tiempo
    t = 0:1/fs:num_bits*bit_period - 1/fs;

    % Determinar qué opciones fueron seleccionadas en el ListBox
    selected_options = cellstr(app.ListBox.Value);

    % Si el usuario selecciona "Todos", se seleccionan todas las opciones
    if any(strcmp(selected_options, 'Todos'))
        selected_options = {'NRZ_Unipolar', 'NRZ_Polar', 'NRZ_Bipolar'};
    else
        % Reemplazar espacios por guiones bajos en los nombres seleccionados
        selected_options = strrep(selected_options, ' ', '_');
    end

    % Limpiar el gráfico antes de dibujar
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');

    % Asignar colores para cada tipo de señal
    colors = struct('NRZ_Unipolar', 'b', 'NRZ_Polar', 'r', 'NRZ_Bipolar', 'g');

    % Dibujar cada codificación seleccionada
    for i = 1:length(selected_options)
        signal_type = selected_options{i};
        
        % Inicializar data_signal con ceros
        data_signal = zeros(size(t));
        samples_per_bit = fs / bit_rate;

        switch signal_type
            case 'NRZ_Unipolar'
                for j = 1:num_bits
                    start_idx = round((j-1) * samples_per_bit) + 1;
                    end_idx = round(j * samples_per_bit);

                    if data_bits(j) == 1
                        data_signal(start_idx:end_idx) = 1;
                    end
                end

            case 'NRZ_Polar'
                for j = 1:num_bits
                    start_idx = round((j-1) * samples_per_bit) + 1;
                    end_idx = round(j * samples_per_bit);

                    if data_bits(j) == 1
                        data_signal(start_idx:end_idx) = 1;
                    else
                        data_signal(start_idx:end_idx) = -1;
                    end
                end

            case 'NRZ_Bipolar'
                last_polarity = -1;

                for j = 1:num_bits
                    start_idx = round((j-1) * samples_per_bit) + 1;
                    end_idx = round(j * samples_per_bit);

                    if data_bits(j) == 1
                        last_polarity = -last_polarity;
                        data_signal(start_idx:end_idx) = last_polarity;
                    end
                end
        end
        
        % Graficar la señal con su color correspondiente
        plot(app.UIAxes, t, data_signal, 'Color', colors.(signal_type), 'LineWidth', 2);
    end

    % Configurar el gráfico
    title(app.UIAxes, 'Comparación de Codificaciones NRZ');
    xlabel(app.UIAxes, 'Tiempo (s)');
    ylabel(app.UIAxes, 'Amplitud');
    ylim(app.UIAxes, [-1.2, 1.2]);
    legend(app.UIAxes, strrep(selected_options, '_', ' '), 'Location', 'northeast');
    grid(app.UIAxes, 'on');
    hold(app.UIAxes, 'off');
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            app.BitSequence.Value = '1010101';
            app.FsInput.Value = 1000;
            app.Duracion.Value = 3;
            

            % Limpiar la gráfica
            cla(app.UIAxes, 'reset'); % Borra la gráfica y restablece ejes
            
            % Mensaje opcional para feedback al usuario
            disp("Valores restablecidos correctamente.");
        end

        % Button pushed function: GenerarSecuencia
        function GenerarSecuenciaButtonPushed(app, event)
            nBits = round(app.nBitsInput.Value);  % Asegurar que sea un número entero

            % Validar que el usuario ingrese un número válido
            if nBits <= 0 || isnan(nBits)
                uialert(app.UIFigure, 'Ingrese un número válido de bits.', 'Error');
                return;
            end
            
            % Generar la secuencia aleatoria de 0s y 1s
            randomBits = randi([0, 1], 1, nBits);
            
            % Convertir el vector numérico a una cadena de texto
            app.BitSequence.Value = num2str(randomBits, '%d');
            
            % Mensaje de depuración (opcional)
            disp(['Secuencia generada: ', app.BitSequence.Value]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CadenadeBitsEditFieldLabel = uilabel(app.UIFigure);
            app.CadenadeBitsEditFieldLabel.Text = 'Cadena de Bits';
            app.BitSequence = uieditfield(app.UIFigure, 'text');
            app.BitSequence.CharacterLimits = [1 Inf];
            app.BitSequence.Value = '1010101';
            app.FrecuenciademuestreofsEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreofsEditFieldLabel.Text = 'Frecuencia de muestreo "fs"';
            app.FsInput = uieditfield(app.UIFigure, 'numeric');
            app.FsInput.Limits = [1 Inf];
            app.FsInput.Value = 1000;
            app.DuracintotaldelasealLabel = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel.Text = 'Duración total de la señal';
            app.Duracion = uieditfield(app.UIFigure, 'numeric');
            app.Duracion.Limits = [1 Inf];
            app.Duracion.Value = 3;
            app.GenerarSecuencia = uibutton(app.UIFigure, 'push');
            app.GenerarSecuencia.ButtonPushedFcn = createCallbackFcn(app, @GenerarSecuenciaButtonPushed, true);
            app.GenerarSecuencia.Text = 'Generar secuencia aleatoria';
            app.DuracintotaldelasealLabel_2 = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel_2.Text = 'Digitar número de Bits deseados';
            app.nBitsInput = uieditfield(app.UIFigure, 'numeric');
            app.nBitsInput.Limits = [1 Inf];
            app.nBitsInput.Value = 1;
            app.DibujarNRZButton = uibutton(app.UIFigure, 'push');
            app.DibujarNRZButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarNRZButtonPushed, true);
            app.DibujarNRZButton.Text = 'Dibujar NRZ';
            app.RestablecerButton = uibutton(app.UIFigure, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            app.TipTextAreaLabel = uilabel(app.UIFigure);
            app.TipTextAreaLabel.Text = 'Tip';
            app.TipTextArea = uitextarea(app.UIFigure);
            app.TipTextArea.Editable = 'off';
            app.TipTextArea.Value = {'Puedes visualizar simultáneamente las codificaciones NRZ Unipolar, Polar y Bipolar marcando las opciones correspondientes. Esto te permitirá comparar sus diferencias fácilmente. Además:'; ''; '    Asegúrate de ingresar una secuencia binaria válida (ejemplo: 1011001).'; ''; '    Puedes ajustar la frecuencia de muestreo y la duración para observar los cambios en la señal.'; ''; '    Si las señales se superponen demasiado, prueba aumentar la duración para separarlas mejor.'};
            app.CuldeseasgrficarListBoxLabel = uilabel(app.UIFigure);
            app.CuldeseasgrficarListBoxLabel.Text = '¿Cuál deseas gráficar?';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'NRZ Unipolar', 'NRZ Polar', 'NRZ Bipolar', 'Todos'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'NRZ Unipolar'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Adicional_NRZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end