classdef MANCHESTER_TH_IEEE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ListBox                      matlab.ui.control.ListBox
        ConcualquieresprobarListBoxLabel  matlab.ui.control.Label
        TipTextArea                  matlab.ui.control.TextArea
        TipTextAreaLabel             matlab.ui.control.Label
        InformacinreaTextArea        matlab.ui.control.TextArea
        InformacinreaTextAreaLabel   matlab.ui.control.Label
        Duracion                     matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel_2  matlab.ui.control.Label
        FsInput                      matlab.ui.control.NumericEditField
        FrecuenciademuestreofsEditFieldLabel  matlab.ui.control.Label
        BitSequence                  matlab.ui.control.EditField
        CadenadeBitsEditFieldLabel   matlab.ui.control.Label
        RestablecerButton            matlab.ui.control.Button
        DibujarNRZButton             matlab.ui.control.Button
        nBitsInput                   matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel    matlab.ui.control.Label
        GenerarSecuencia             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarSecuencia
        function GenerarSecuenciaButtonPushed(app, event)
            nBits = round(app.nBitsInput.Value);  % Asegurar que sea un número entero

            % Validar que el usuario ingrese un número válido
            if nBits <= 0 || isnan(nBits)
                uialert(app.UIFigure, 'Ingrese un número válido de bits.', 'Error');
                return;
            end
            
            % Generar la secuencia aleatoria de 0s y 1s
            randomBits = randi([0, 1], 1, nBits);
            
            % Convertir el vector numérico a una cadena de texto
            app.BitSequence.Value = num2str(randomBits, '%d');
            
            % Mensaje de depuración (opcional)
            disp(['Secuencia generada: ', app.BitSequence.Value]);
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            app.BitSequence.Value = '1010101';
            app.FsInput.Value = 1000;
            app.Duracion.Value = 3;
            

            % Limpiar la gráfica
            cla(app.UIAxes, 'reset'); % Borra la gráfica y restablece ejes
            
            % Mensaje opcional para feedback al usuario
            disp("Valores restablecidos correctamente.");
        end

        % Button pushed function: DibujarNRZButton
        function DibujarNRZButtonPushed(app, event)
                try
                    % Capturar la secuencia de bits del usuario
                    user_input = num2str(app.BitSequence.Value);
                    user_input = regexprep(user_input, '[^01]', ''); % Filtrar solo 0s y 1s
                    
                    if isempty(user_input)
                        error("Ingrese al menos un bit válido.");
                    end
                    
                    % Convertir la cadena en un vector numérico
                    data_bits = double(user_input) - '0';
                          catch
                          uialert(app.UIFigure, 'Ingrese una secuencia binaria válida (ej: 1010101).', 'Error');
                          return;
                end
                    selected_option = app.ListBox.Value;  % Obtiene la opción seleccionada

                    % Usa strcmp o switch para comparar correctamente
                    if strcmp(selected_option, 'Thomas')
                        % Código para Manchester Thomas
                    elseif strcmp(selected_option, 'IEEE')
                        % Código para Manchester IEEE
                    else
                        uialert(app.UIFigure, 'Seleccione una opción válida.', 'Error');
                    end
            % Obtener la selección del usuario
            selected_options = app.ListBox.Value;  % Lista de codificaciones seleccionadas
            fs = app.FsInput.Value;  % Frecuencia de muestreo
            duration = app.Duracion.Value;  % Duración de la señal
            num_bits = length(data_bits);
        
            % Calcular el bit rate dinámico
            bit_rate = num_bits / duration;
            bit_period = 1 / bit_rate;
            t = 0:1/fs:num_bits*bit_period - 1/fs; % Eje de tiempo
            samples_per_bit = fs / bit_rate;
        
            % Limpiar el gráfico antes de dibujar
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % Verificar si se seleccionaron ambas opciones (Thomas e IEEE)
            if any(strcmp(selected_options, 'Thomas')) && any(strcmp(selected_options, 'IEEE'))
                selected_options = {'Thomas', 'IEEE'}; % Para graficar ambas
            end
        
            % -------------------------------
            % Generación y graficado de cada señal
            % -------------------------------
            for i = 1:length(selected_options)
                switch selected_options{i}
                    case 'Thomas'
                        data_signal = zeros(size(t));
                        for j = 1:num_bits
                            start_idx = round((j-1) * samples_per_bit) + 1;
                            mid_idx = round(start_idx + samples_per_bit / 2);
                            end_idx = round(j * samples_per_bit);
                            
                            if data_bits(j) == 1
                                data_signal(start_idx:mid_idx) = -1; % ↓
                                data_signal(mid_idx:end_idx) = 1;   % ↑
                            else
                                data_signal(start_idx:mid_idx) = 1;  % ↑
                                data_signal(mid_idx:end_idx) = -1;  % ↓
                            end
                        end
                        plot(app.UIAxes, t, data_signal, 'LineWidth', 2, 'DisplayName', 'Manchester Thomas');
        
                    case 'IEEE'
                        data_signal = zeros(size(t));
                        for j = 1:num_bits
                            start_idx = round((j-1) * samples_per_bit) + 1;
                            mid_idx = round(start_idx + samples_per_bit / 2);
                            end_idx = round(j * samples_per_bit);
                            
                            if data_bits(j) == 1
                                data_signal(start_idx:mid_idx) = 1;  % ↑
                                data_signal(mid_idx:end_idx) = -1;  % ↓
                            else
                                data_signal(start_idx:mid_idx) = -1; % ↓
                                data_signal(mid_idx:end_idx) = 1;   % ↑
                            end
                        end
                        plot(app.UIAxes, t, data_signal, 'LineWidth', 2, 'DisplayName', 'Manchester IEEE');
                end
            end
        
            % Configuración del gráfico
            title(app.UIAxes, 'Señal Codificada');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            ylim(app.UIAxes, [-1.5 1.5]);  % Ajustar la escala
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'show'); % Mostrar leyenda si hay múltiples señales
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bipolar NRZ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenerarSecuencia = uibutton(app.UIFigure, 'push');
            app.GenerarSecuencia.ButtonPushedFcn = createCallbackFcn(app, @GenerarSecuenciaButtonPushed, true);
            app.GenerarSecuencia.Text = 'Generar secuencia aleatoria';
            app.DuracintotaldelasealLabel = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel.Text = 'Digitar número de Bits deseados';
            app.nBitsInput = uieditfield(app.UIFigure, 'numeric');
            app.nBitsInput.Limits = [1 Inf];
            app.nBitsInput.Value = 1;
            app.DibujarNRZButton = uibutton(app.UIFigure, 'push');
            app.DibujarNRZButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarNRZButtonPushed, true);
            app.DibujarNRZButton.Text = 'Dibujar NRZ';
            app.RestablecerButton = uibutton(app.UIFigure, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            app.CadenadeBitsEditFieldLabel = uilabel(app.UIFigure);
            app.CadenadeBitsEditFieldLabel.Text = 'Cadena de Bits';
            app.BitSequence = uieditfield(app.UIFigure, 'text');
            app.BitSequence.CharacterLimits = [1 Inf];
            app.BitSequence.Value = '1010101';
            app.FrecuenciademuestreofsEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreofsEditFieldLabel.Text = 'Frecuencia de muestreo "fs"';
            app.FsInput = uieditfield(app.UIFigure, 'numeric');
            app.FsInput.Limits = [1 Inf];
            app.FsInput.Value = 1000;
            app.DuracintotaldelasealLabel_2 = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel_2.Text = 'Duración total de la señal';
            app.Duracion = uieditfield(app.UIFigure, 'numeric');
            app.Duracion.Limits = [1 Inf];
            app.Duracion.Value = 3;
            app.InformacinreaTextAreaLabel = uilabel(app.UIFigure);
            app.InformacinreaTextAreaLabel.Text = 'Información área';
            app.InformacinreaTextArea = uitextarea(app.UIFigure);
            app.InformacinreaTextArea.Editable = 'off';
            app.InformacinreaTextArea.Value = {'💡 Codificación Manchester Thomas'; 'La codificación Manchester Thomas es una técnica de transmisión en la que cada bit se representa con dos transiciones dentro de un período de bit.'; ''; '    Un 1 se codifica con una transición baja-alta (↓↑).'; ''; '    Un 0 se codifica con una transición alta-baja (↑↓).'; '    Esta técnica es autosincronizable, lo que ayuda a evitar problemas con largas secuencias de ceros o unos.'; ''; '📌 Usos comunes:'; ''; '    Redes Ethernet'; ''; '    Comunicaciones en serie'; ''; '    RFID y protocolos industriales'; ''};
            app.TipTextAreaLabel = uilabel(app.UIFigure);
            app.TipTextAreaLabel.Text = 'Tip';
            app.TipTextArea = uitextarea(app.UIFigure);
            app.TipTextArea.Editable = 'off';
            app.TipTextArea.Value = {'✔ Asegúrate de ingresar una secuencia binaria válida (Ejemplo: 1100101).'; '✔ Si la gráfica no se ve bien, prueba aumentando la frecuencia de muestreo (fs).'; '✔ Experimenta con diferentes duraciones para ver el impacto en la representación de la señal.'; '✔ Si quieres comparar con NRZ, selecciona ambas codificaciones en el ListBox.'};
            app.ConcualquieresprobarListBoxLabel = uilabel(app.UIFigure);
            app.ConcualquieresprobarListBoxLabel.Text = '¿Con cual quieres probar?';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Thomas', 'IEEE'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Thomas'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MANCHESTER_TH_IEEE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end