classdef Polar_NRZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RestablecerButton            matlab.ui.control.Button
        DibujarNRZButton             matlab.ui.control.Button
        nBitsInput                   matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel_2  matlab.ui.control.Label
        GenerarSecuencia             matlab.ui.control.Button
        TipTextArea                  matlab.ui.control.TextArea
        TipTextAreaLabel             matlab.ui.control.Label
        InformacinreaTextArea        matlab.ui.control.TextArea
        InformacinreaTextAreaLabel   matlab.ui.control.Label
        sLabel                       matlab.ui.control.Label
        Duracion                     matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel    matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        FsInput                      matlab.ui.control.NumericEditField
        FrecuenciademuestreofsEditFieldLabel  matlab.ui.control.Label
        BitSequence                  matlab.ui.control.EditField
        CadenadeBitsEditFieldLabel   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarSecuencia
        function GenerarSecuenciaButtonPushed(app, event)
            nBits = round(app.nBitsInput.Value);  % Asegurar que sea un número entero

            % Validar que el usuario ingrese un número válido
            if nBits <= 0 || isnan(nBits)
                uialert(app.UIFigure, 'Ingrese un número válido de bits.', 'Error');
                return;
            end
            
            % Generar la secuencia aleatoria de 0s y 1s
            randomBits = randi([0, 1], 1, nBits);
            
            % Convertir el vector numérico a una cadena de texto
            app.BitSequence.Value = num2str(randomBits, '%d');
            
            % Mensaje de depuración (opcional)
            disp(['Secuencia generada: ', app.BitSequence.Value]);
        end

        % Button pushed function: DibujarNRZButton
        function DibujarNRZButtonPushed(app, event)
            try
            % Capturar la secuencia de bits del usuario
            user_input = num2str(app.BitSequence.Value);
        
            % Limpiar caracteres no binarios
            user_input = regexprep(user_input, '[^01]', ''); 
        
            % Verificar si la entrada está vacía después de limpiar
            if isempty(user_input)
                error("Ingrese al menos un bit válido.");
            end
        
            % Convertir la cadena en un vector numérico
            data_bits = double(user_input) - '0';
        
            catch
                uialert(app.UIFigure, 'Ingrese una secuencia binaria válida (ej: 1010101).', 'Error');
            return;
            end
        
        % Obtener parámetros de entrada
        fs = app.FsInput.Value;  % Frecuencia de muestreo
        duration = app.Duracion.Value;  % Duración de la señal en segundos
        num_bits = length(data_bits);
        
        % Calcular el bit rate dinámicamente
        bit_rate = num_bits / duration;
        bit_period = 1 / bit_rate;
        
        % Crear el eje de tiempo
        t = 0:1/fs:num_bits*bit_period - 1/fs;
        
        % Generar la señal NRZ Polar
        data_signal = zeros(size(t));
        samples_per_bit = fs / bit_rate;
        
        for i = 1:num_bits
            start_idx = round((i-1) * samples_per_bit) + 1;
            end_idx = round(i * samples_per_bit);
            
            % Asignación para NRZ Polar
            if data_bits(i) == 1
                data_signal(start_idx:end_idx) = 1;   % Bit "1" → +V
            else
                data_signal(start_idx:end_idx) = -1;  % Bit "0" → -V
            end
        end
        
        % Graficar la señal
        plot(app.UIAxes, t, data_signal, 'LineWidth', 2);
        title(app.UIAxes, 'Señal NRZ Polar');
        xlabel(app.UIAxes, 'Tiempo (s)');
        ylabel(app.UIAxes, 'Amplitud');
        ylim(app.UIAxes, [-1.2 1.2]);  % Ajuste de escala para NRZ Polar
        grid(app.UIAxes, 'on');
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            app.BitSequence.Value = "1010101";  % Deja el campo de bits vacío
            app.FsInput.Value = 500;  % Restablecer la frecuencia de muestreo
            app.Duracion.Value= 5;
            app.nBitsInput.Value = 1;
            

            % Limpiar la gráfica
            cla(app.UIAxes, 'reset'); % Borra la gráfica y restablece ejes
            
            % Mensaje opcional para feedback al usuario
            disp("Valores restablecidos correctamente.");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Polar NRZ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CadenadeBitsEditFieldLabel = uilabel(app.UIFigure);
            app.CadenadeBitsEditFieldLabel.Text = 'Cadena de Bits';
            app.BitSequence = uieditfield(app.UIFigure, 'text');
            app.BitSequence.CharacterLimits = [1 Inf];
            app.BitSequence.Value = '1010101';
            app.FrecuenciademuestreofsEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreofsEditFieldLabel.Text = 'Frecuencia de muestreo "fs"';
            app.FsInput = uieditfield(app.UIFigure, 'numeric');
            app.FsInput.Limits = [1 Inf];
            app.FsInput.Value = 500;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Interpreter = 'latex';
            app.HzLabel.Text = 'Hz';
            app.DuracintotaldelasealLabel = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel.Text = 'Duración total de la señal';
            app.Duracion = uieditfield(app.UIFigure, 'numeric');
            app.Duracion.Limits = [1 Inf];
            app.Duracion.Value = 5;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Interpreter = 'latex';
            app.sLabel.Text = 's';
            app.InformacinreaTextAreaLabel = uilabel(app.UIFigure);
            app.InformacinreaTextAreaLabel.Text = 'Información área';
            app.InformacinreaTextArea = uitextarea(app.UIFigure);
            app.InformacinreaTextArea.Editable = 'off';
            app.InformacinreaTextArea.Value = {'En esta aplicación, el usuario podrá:'; '✅ Generar secuencias de bits aleatorias.'; '✅ Visualizar la representación gráfica de la secuencia binaria.'; '✅ Observar la señal resultante codificada en NRZ Polar.'; ''; 'La codificación NRZ (Non-Return to Zero) Polar es un esquema de modulación de señales digitales en el que cada bit se representa con un nivel de voltaje diferente.'; ''; '➯ Asignación de voltajes:'; ''; 'Bit "1" → Se representa con un nivel alto de voltaje (+V).'; ''; 'Bit "0" → Se representa con un nivel bajo de voltaje (-V).'; ''; '➳ Características principales:'; ''; '✔ No hay componente de corriente continua prominente, lo que mejora la transmisión en largas distancias.'; ''; '✔Mayor eficiencia en comparación con Unipolar, ya que la señal oscila alrededor de cero.'; ''; '✔ La sincronización mejora en comparación con Unipolar, pero sigue habiendo problemas si hay secuencias largas de "1" o "0".'; ''};
            app.TipTextAreaLabel = uilabel(app.UIFigure);
            app.TipTextAreaLabel.Text = 'Tip';
            app.TipTextArea = uitextarea(app.UIFigure);
            app.TipTextArea.Editable = 'off';
            app.TipTextArea.Value = {'⭢ Si aumentas la frecuencia de muestreo (fs), la señal será más precisa.'; '⭢ Experimenta con la duración de la señal para ver cómo cambia la representación de los bits en el tiempo.'; '⭢ Compara NRZ Polar con Unipolar para notar las diferencias en la asignación de voltajes.'; '⭢ Usa los botones de la aplicación para probar diferentes secuencias y analizar su comportamiento.'};
            app.GenerarSecuencia = uibutton(app.UIFigure, 'push');
            app.GenerarSecuencia.ButtonPushedFcn = createCallbackFcn(app, @GenerarSecuenciaButtonPushed, true);
            app.GenerarSecuencia.Text = 'Generar secuencia aleatoria';
            app.DuracintotaldelasealLabel_2 = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel_2.Text = 'Digitar número de Bits deseados';
            app.nBitsInput = uieditfield(app.UIFigure, 'numeric');
            app.nBitsInput.Limits = [1 Inf];
            app.nBitsInput.Value = 1;
            app.DibujarNRZButton = uibutton(app.UIFigure, 'push');
            app.DibujarNRZButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarNRZButtonPushed, true);
            app.DibujarNRZButton.Text = 'Dibujar NRZ';
            app.RestablecerButton = uibutton(app.UIFigure, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Polar_NRZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end