classdef Bipolar_NRZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TipTextArea                  matlab.ui.control.TextArea
        TipTextAreaLabel             matlab.ui.control.Label
        InformacinreaTextArea        matlab.ui.control.TextArea
        InformacinreaTextAreaLabel   matlab.ui.control.Label
        RestablecerButton            matlab.ui.control.Button
        DibujarNRZButton             matlab.ui.control.Button
        nBitsInput                   matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel_2  matlab.ui.control.Label
        GenerarSecuencia             matlab.ui.control.Button
        Duracion                     matlab.ui.control.NumericEditField
        DuracintotaldelasealLabel    matlab.ui.control.Label
        FsInput                      matlab.ui.control.NumericEditField
        FrecuenciademuestreofsEditFieldLabel  matlab.ui.control.Label
        BitSequence                  matlab.ui.control.EditField
        CadenadeBitsEditFieldLabel   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarSecuencia
        function GenerarSecuenciaButtonPushed(app, event)
            nBits = round(app.nBitsInput.Value);  % Asegurar que sea un número entero

            % Validar que el usuario ingrese un número válido
            if nBits <= 0 || isnan(nBits)
                uialert(app.UIFigure, 'Ingrese un número válido de bits.', 'Error');
                return;
            end
            
            % Generar la secuencia aleatoria de 0s y 1s
            randomBits = randi([0, 1], 1, nBits);
            
            % Convertir el vector numérico a una cadena de texto
            app.BitSequence.Value = num2str(randomBits, '%d');
            
            % Mensaje de depuración (opcional)
            disp(['Secuencia generada: ', app.BitSequence.Value]);
        end

        % Button pushed function: DibujarNRZButton
        function DibujarNRZButtonPushed(app, event)
             try
                % Capturar la secuencia de bits del usuario
                user_input = num2str(app.BitSequence.Value);
                user_input = regexprep(user_input, '[^01]', '');  % Limpiar caracteres no válidos

                if isempty(user_input)
                    error("Ingrese una secuencia binaria válida.");
                end

                % Convertir la secuencia en vector numérico
                data_bits = double(user_input) - '0';

            catch
                uialert(app.UIFigure, 'Ingrese una secuencia binaria válida (ej: 10101).', 'Error');
                return;
            end

            % Obtener parámetros de entrada
            fs = app.FsInput.Value;  % Frecuencia de muestreo
            duration = app.Duracion.Value;  % Duración de la señal en segundos
            num_bits = length(data_bits);

            % Calcular el bit rate dinámicamente
            bit_rate = num_bits / duration;
            bit_period = 1 / bit_rate;

            % Crear el eje de tiempo
            t = 0:1/fs:num_bits*bit_period - 1/fs;
            data_signal = zeros(size(t));
            samples_per_bit = fs / bit_rate;

            % Inicializar el nivel bipolar
            last_level = 1; 

            % Generar la señal NRZ Bipolar
            for i = 1:num_bits
                start_idx = round((i-1) * samples_per_bit) + 1;
                end_idx = round(i * samples_per_bit);

                if data_bits(i) == 1
                    data_signal(start_idx:end_idx) = last_level;
                    last_level = -last_level; % Alternar entre +V y -V
                else
                    data_signal(start_idx:end_idx) = 0; % Bit "0" es siempre 0V
                end
            end

            % Graficar la señal
            plot(app.UIAxes, t, data_signal, 'LineWidth', 2);
            title(app.UIAxes, 'Señal NRZ Bipolar');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            ylim(app.UIAxes, [-1.5 1.5]); 
            grid(app.UIAxes, 'on');
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            app.BitSequence.Value = '1010101';
            app.FsInput.Value = 1000;
            app.Duracion.Value = 3;
            

            % Limpiar la gráfica
            cla(app.UIAxes, 'reset'); % Borra la gráfica y restablece ejes
            
            % Mensaje opcional para feedback al usuario
            disp("Valores restablecidos correctamente.");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bipolar NRZ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CadenadeBitsEditFieldLabel = uilabel(app.UIFigure);
            app.CadenadeBitsEditFieldLabel.Text = 'Cadena de Bits';
            app.BitSequence = uieditfield(app.UIFigure, 'text');
            app.BitSequence.CharacterLimits = [1 Inf];
            app.BitSequence.Value = '1010101';
            app.FrecuenciademuestreofsEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreofsEditFieldLabel.Text = 'Frecuencia de muestreo "fs"';
            app.FsInput = uieditfield(app.UIFigure, 'numeric');
            app.FsInput.Limits = [1 Inf];
            app.FsInput.Value = 1000;
            app.DuracintotaldelasealLabel = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel.Text = 'Duración total de la señal';
            app.Duracion = uieditfield(app.UIFigure, 'numeric');
            app.Duracion.Limits = [1 Inf];
            app.Duracion.Value = 3;
            app.GenerarSecuencia = uibutton(app.UIFigure, 'push');
            app.GenerarSecuencia.ButtonPushedFcn = createCallbackFcn(app, @GenerarSecuenciaButtonPushed, true);
            app.GenerarSecuencia.Text = 'Generar secuencia aleatoria';
            app.DuracintotaldelasealLabel_2 = uilabel(app.UIFigure);
            app.DuracintotaldelasealLabel_2.Text = 'Digitar número de Bits deseados';
            app.nBitsInput = uieditfield(app.UIFigure, 'numeric');
            app.nBitsInput.Limits = [1 Inf];
            app.nBitsInput.Value = 1;
            app.DibujarNRZButton = uibutton(app.UIFigure, 'push');
            app.DibujarNRZButton.ButtonPushedFcn = createCallbackFcn(app, @DibujarNRZButtonPushed, true);
            app.DibujarNRZButton.Text = 'Dibujar NRZ';
            app.RestablecerButton = uibutton(app.UIFigure, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            app.InformacinreaTextAreaLabel = uilabel(app.UIFigure);
            app.InformacinreaTextAreaLabel.Text = 'Información área';
            app.InformacinreaTextArea = uitextarea(app.UIFigure);
            app.InformacinreaTextArea.Editable = 'off';
            app.InformacinreaTextArea.Value = {'En esta codificación, los bits ''1'' alternan entre un nivel positivo (+V) y un nivel negativo (-V), mientras que los bits ''0'' se representan con un nivel de voltaje neutro (0V).'; ''; 'Esta alternancia de polaridad en los ''1'' ayuda a reducir el componente de corriente continua (DC) en la señal, lo que mejora la sincronización y facilita la transmisión en medios que bloquean señales con DC.'; ''; '➱ Reglas de la Codificación:'; ''; '➮ Bit "1" → Se alterna entre +V y -V.'; '➮ Bit "0" → Siempre se representa con 0V.'; ''; 'Ejemplo de Secuencia:'; 'Entrada: 1 0 1 1 0 0 1 0 1'; 'Salida: +V 0 -V +V 0 0 -V 0 +V'; ''};
            app.TipTextAreaLabel = uilabel(app.UIFigure);
            app.TipTextAreaLabel.Text = 'Tip';
            app.TipTextArea = uitextarea(app.UIFigure);
            app.TipTextArea.Editable = 'off';
            app.TipTextArea.Value = {'➳ ¿Cómo interpretar la señal generada?'; ''; '➴ Si ves que los ''1'' no están alternando  entre +V y -V, revisa la secuencia de bits.'; ''; '➴ La presencia de ceros prolongados podría afectar la sincronización.'; ''; '➳ ¿Cómo ajustar la visualización?'; ''; '➴ Aumentar la frecuencia de muestreo (fs) mejora la precisión de la señal.'; ''; '➴ Reducir la duración de la señal hace que los bits se representen más rápido.'; ''; '➴ ¿Por qué es mejor que NRZ Unipolar?'; ''; '➴ Reduce el componente DC, evitando acumulación de carga en la transmisión.'; ''; '➴ Mejora la detección de errores gracias a la alternancia de los ''1''.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bipolar_NRZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end